classdef ElevationPro_Matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElevationProUIFigure      matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OptionsMenu               matlab.ui.container.Menu
        RightPanel                matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        TextArea                  matlab.ui.control.TextArea
        StatisticsLabel           matlab.ui.control.Label
        UIAnglePlot               matlab.ui.control.UIAxes
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout6               matlab.ui.container.GridLayout
        RootFolderButton          matlab.ui.control.Button
        RootFolderEditField       matlab.ui.control.EditField
        RootFolderEditFieldLabel  matlab.ui.control.Label
        ExportDataPanel           matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        ExportButton              matlab.ui.control.Button
        VisualizationPanel        matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        FolderButton              matlab.ui.control.Button
        FolderEditField           matlab.ui.control.EditField
        FolderEditFieldLabel      matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        ThoraxButton              matlab.ui.control.Button
        LShoulderButton           matlab.ui.control.Button
        RShoulderButton           matlab.ui.control.Button
        AngleSpinner              matlab.ui.control.Spinner
        AngleSpinnerLabel         matlab.ui.control.Label
        ThoraxEditField           matlab.ui.control.EditField
        ThoraxEditFieldLabel      matlab.ui.control.Label
        LShoulderEditField        matlab.ui.control.EditField
        LShoulderEditFieldLabel   matlab.ui.control.Label
        RShoulderEditField        matlab.ui.control.EditField
        RShoulderEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        workingdirectory
        
        
        % Trial data file path
        thoraxTrialFilePath
        RShoulderFilePath
        LShoulderFilePath
        RootFolderPath
        
        RAngle
        LAngle
        thresholdAngle = 60
        rootPath

        quatCol = ["Quat_q0", "Quat_q1", "Quat_q2", "Quat_q3"];
    end
    
    methods (Access = private)
        
        function filepath = getFile(app, root)
            f = figure('Position', [-100 -100 0 0]);
            if isequal(root,0)
                [file,path] = uigetfile("*.txt", 'Select a File');
         
            else
                [file,path] = uigetfile("*.txt", 'Select a File', root);
            end
            delete(f);
                  
            if isequal(file,0)
                uialert(app.ElevationProUIFigure, 'No file selected.','Warning');
                filepath = "";
            else
                filepath = append(path, file);
            end
        end


        function table = getTable(~, filepath)
            opts = detectImportOptions(filepath);
            table = readtable(filepath, opts);
        end
        
        function folderpath = getFolder(app, root)
            f = figure('Position', [-100 -100 0 0]);
            folderpath = uigetdir(root);
            delete(f);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RShoulderButton
        function RShoulderButtonPushed(app, event)
            app.RShoulderEditField.Value = app.getFile(app.rootPath);
        end

        % Button pushed function: LShoulderButton
        function LShoulderButtonPushed(app, event)
            app.LShoulderEditField.Value = app.getFile(app.rootPath);
        end

        % Button pushed function: ThoraxButton
        function ThoraxButtonPushed(app, event)
            app.ThoraxEditField.Value = app.getFile(app.rootPath);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            try
 
                thoraxTable = app.getTable(app.ThoraxEditField.Value);
                LShoulderTable = app.getTable(app.LShoulderEditField.Value);
                RShoulderTable = app.getTable(app.RShoulderEditField.Value);
                % check data integrity in the first place
                

                q1 = thoraxTable{:, app.quatCol};
                q2 = RShoulderTable{:, app.quatCol};
                q3 =  LShoulderTable{:, app.quatCol};
                qR = quaternion(quatmultiply(quatconj(q2), q1));
                qL = quaternion(quatmultiply(quatconj(q3), q1));
                mR = quat2rotm(qR);
                mL = quat2rotm(qL);
                aR = rad2deg(acos(reshape(mR(3,3,:),[], 1)));
                aL = rad2deg(acos(reshape(mL(3,3,:),[], 1)));
                tR = sum(aR >= app.thresholdAngle) * 0.01;
                tL = sum(aL >= app.thresholdAngle) * 0.01;
                tT = numel(aR) * 0.01;
                pR = (tR/tT) * 100;
                pL = (tL/tT) * 100;
                app.TextArea.Value = ["Total Time (s): " + tT, ...
                                        "Time over " + app.thresholdAngle + char(176) + " (s): R = " + tR + " ; L = " + tL, ...
                                        "Percentage of time over " + app.thresholdAngle + char(176) + " (%): R = " + pR + " ; L = " + pL];

                plot(app.UIAnglePlot, aR, 'DisplayName', 'Right Arm');
                hold(app.UIAnglePlot);
                plot(app.UIAnglePlot, aL, 'DisplayName', 'Left Arm');
                hold(app.UIAnglePlot);
                yline(app.UIAnglePlot, app.thresholdAngle,'-.r', 'DisplayName', 'Threshold');
                legend(app.UIAnglePlot);
                app.RAngle = aR;
                app.LAngle = aL;

            catch ME
                message = sprintf('Error in calculation:\n%s', ME.message);
                uiwait(warndlg(message));
            end
        end

        % Value changed function: AngleSpinner
        function AngleSpinnerValueChanged(app, event)
            value = app.AngleSpinner.Value;
            app.thresholdAngle = value;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isequal(app.RootFolderEditField.Value,0)
                return 
            end
            
            topLevelFolder = app.RootFolderEditField.Value; % or whatever, such as 'C:\Users\John\Documents\MATLAB\work'
            % Get a list of all files and folders in this folder.
            files = dir(topLevelFolder);
            % Get a logical vector that tells which is a directory.
            dirFlags = [files.isdir];
            % Extract only those that are directories.
            subFolders = files(dirFlags); % A structure with extra info.
            % Get only the folder names into a cell array.
            subFolderNames = {subFolders(3:end).name} % Start at 3 to skip . and ..
            
            
            filePattern = fullfile(subFolder, '*.txt');
            textFiles = dir(filePattern);
        end

        % Button pushed function: RootFolderButton
        function RootFolderButtonPushed(app, event)
            app.RootFolderEditField.Value = app.getFolder(app.rootPath);
            app.rootPath = app.RootFolderEditField.Value;
            
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            try
                app.FolderEditField.Value = app.getFolder(app.rootPath);
                rootfolder = app.FolderEditField.Value;
                
                leftFilePattern = fullfile(rootfolder, '*000_00B4A7C9.txt');
                rightFilePattern = fullfile(rootfolder, '*000_00B4AF58.txt');
                centerFilePattern = fullfile(rootfolder, '*000_00B4A9E6.txt');
                leftFile = dir(leftFilePattern);
                rightFile = dir(rightFilePattern);
                centerFile = dir(centerFilePattern);
                
                app.RShoulderEditField.Value = fullfile(rootfolder, rightFile(1).name);
                app.LShoulderEditField.Value = fullfile(rootfolder, leftFile(1).name);
                app.ThoraxEditField.Value = fullfile(rootfolder, centerFile(1).name);
            catch ME
                message = sprintf('Error in open files:\n%s', ME.message);
                uiwait(warndlg(message));
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ElevationProUIFigure = uifigure('Visible', 'off');
            app.ElevationProUIFigure.Name = 'ElevationPro';
            app.ElevationProUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'angle.png');
            app.FileMenu = uimenu(app.ElevationProUIFigure);
            app.FileMenu.Text = 'File';
            app.OptionsMenu = uimenu(app.ElevationProUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.LeftPanel = uipanel(app.ElevationProUIFigure);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.VisualizationPanel = uipanel(app.GridLayout2);
            app.VisualizationPanel.Title = 'Visualization';
            app.GridLayout4 = uigridlayout(app.VisualizationPanel);
            app.RShoulderEditFieldLabel = uilabel(app.GridLayout4);
            app.RShoulderEditFieldLabel.Text = 'R Shoulder';
            app.RShoulderEditField = uieditfield(app.GridLayout4, 'text');
            app.LShoulderEditFieldLabel = uilabel(app.GridLayout4);
            app.LShoulderEditFieldLabel.Text = 'L Shoulder';
            app.LShoulderEditField = uieditfield(app.GridLayout4, 'text');
            app.ThoraxEditFieldLabel = uilabel(app.GridLayout4);
            app.ThoraxEditFieldLabel.Text = 'Thorax';
            app.ThoraxEditField = uieditfield(app.GridLayout4, 'text');
            app.AngleSpinnerLabel = uilabel(app.GridLayout4);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.AngleSpinner = uispinner(app.GridLayout4);
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleSpinnerValueChanged, true);
            app.AngleSpinner.Value = 60;
            app.RShoulderButton = uibutton(app.GridLayout4, 'push');
            app.RShoulderButton.ButtonPushedFcn = createCallbackFcn(app, @RShoulderButtonPushed, true);
            app.RShoulderButton.Icon = fullfile(pathToMLAPP, 'icons', 'files.png');
            app.RShoulderButton.Text = '';
            app.LShoulderButton = uibutton(app.GridLayout4, 'push');
            app.LShoulderButton.ButtonPushedFcn = createCallbackFcn(app, @LShoulderButtonPushed, true);
            app.LShoulderButton.Icon = fullfile(pathToMLAPP, 'icons', 'files.png');
            app.LShoulderButton.Text = '';
            app.ThoraxButton = uibutton(app.GridLayout4, 'push');
            app.ThoraxButton.ButtonPushedFcn = createCallbackFcn(app, @ThoraxButtonPushed, true);
            app.ThoraxButton.Icon = fullfile(pathToMLAPP, 'icons', 'files.png');
            app.ThoraxButton.Text = '';
            app.CalculateButton = uibutton(app.GridLayout4, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Icon = fullfile(pathToMLAPP, 'icons', 'calculator.png');
            app.CalculateButton.Text = 'Calculate';
            app.FolderEditFieldLabel = uilabel(app.GridLayout4);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.GridLayout4, 'text');
            app.FolderButton = uibutton(app.GridLayout4, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder.png');
            app.FolderButton.Text = '';
            app.ExportDataPanel = uipanel(app.GridLayout2);
            app.ExportDataPanel.Enable = 'off';
            app.ExportDataPanel.Title = 'Export Data';
            app.GridLayout5 = uigridlayout(app.ExportDataPanel);
            app.ExportButton = uibutton(app.GridLayout5, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.RootFolderEditFieldLabel = uilabel(app.GridLayout6);
            app.RootFolderEditFieldLabel.Text = 'Working Directory';
            app.RootFolderEditField = uieditfield(app.GridLayout6, 'text');
            app.RootFolderButton = uibutton(app.GridLayout6, 'push');
            app.RootFolderButton.ButtonPushedFcn = createCallbackFcn(app, @RootFolderButtonPushed, true);
            app.RootFolderButton.Icon = fullfile(pathToMLAPP, 'icons', 'magnifier.png');
            app.RootFolderButton.Text = '';
            app.RightPanel = uipanel(app.ElevationProUIFigure);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAnglePlot = uiaxes(app.GridLayout3);
            title(app.UIAnglePlot, 'Arm Elevation Angle')
            xlabel(app.UIAnglePlot, 'Time (s)')
            ylabel(app.UIAnglePlot, 'Elevation Angle (Degree)')
            zlabel(app.UIAnglePlot, 'Z')
            app.StatisticsLabel = uilabel(app.GridLayout3);
            app.StatisticsLabel.Text = 'Statistics:';
            app.TextArea = uitextarea(app.GridLayout3);
            % Show the figure after all components are created
            app.ElevationProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElevationPro_Matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElevationProUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElevationProUIFigure)
        end
    end
end